classdef EFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EFECTODELAFRECUENCIADEMUESTREOfsLabel  matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        cal             matlab.ui.control.Button
        grafica         matlab.ui.control.Button
        limpiar         matlab.ui.control.Button
        Grafic          matlab.ui.control.UIAxes
        Label_3         matlab.ui.control.Label
        Grafic_2        matlab.ui.control.UIAxes
        ftLabel         matlab.ui.control.Label
        ft              matlab.ui.control.EditField
        ModificarLabel  matlab.ui.control.Label
        fsLabel         matlab.ui.control.Label
        Fs              matlab.ui.control.EditField
        nLabel          matlab.ui.control.Label
        N               matlab.ui.control.EditField
        Button          matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calcula y grafica el Efecto de la frecuencia de muestreo fs
            syms t
            fs=str2double(app.Fs.Value); %frecuencia de muestreo
            n=str2double(app.N.Value); %número de datos
            dt=1/fs;
            f=str2sym(app.ft.Value); %muestras, datos
            X(t)=f;
            t=(0:n-1)*dt;
            x=double(X(t));
            
            tt=0:0.05:8;
            xx=tt.*exp(-tt);
            plot(app.Grafic_2,tt,xx,'b',t,x,'ro','markersize',4,'markeredgecolor','r','markerfacecolor','r')
            
            wc=pi*fs; %frecuencia límite de Nyquist
            ww=-4*pi:0.1:4*pi;
            Fw=1./(1+1i*ww).^2;
            
            %transformada rápida de Fourier
            y=fft(x,n);
            g=fftshift(y);
            dw=2*pi/(n*dt);
            w=(-n/2:n/2-1)*dw;
            %Grafica
            plot(app.Grafic,ww,abs(Fw),'b',w,abs(g)/fs,'ro','markersize',3,'markeredgecolor','r','markerfacecolor','r')
            app.Grafic.XTick=[-4*pi:2*pi:4*pi]
            app.Grafic.XTickLabel={'-4\pi','-2\pi','0','2\pi','4\pi'}
            app.Grafic.XLim=[-4*pi,4*pi]
            
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia cada casilla y restablece cada grafica
            app.ft.Value='';
            app.Fs.Value='';
            app.N.Value='';
            plot(app.Grafic,0,0)
            plot(app.Grafic_2,0,0)
            app.Grafic.XTick=[]
            app.Grafic.XTickLabel={''};
            app.Grafic.XLim=[-inf inf]
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            FFT %Ejecuta dicho archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            FG
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EFECTODELAFRECUENCIADEMUESTREOfsLabel = uilabel(app.UIFigure);
            app.EFECTODELAFRECUENCIADEMUESTREOfsLabel.Text = 'EFECTO DE LA FRECUENCIA DE MUESTREO fs';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ingrese la función f(t), Ejm. ';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.grafica = uibutton(app.UIFigure, 'push');
            app.grafica.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Transformada')
            xlabel(app.Grafic, '\omega')
            ylabel(app.Grafic, '|F(\omega)|')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '|';
            app.Grafic_2 = uiaxes(app.UIFigure);
            title(app.Grafic_2, 'f(t)')
            xlabel(app.Grafic_2, 't')
            ylabel(app.Grafic_2, 'x')
            app.ftLabel = uilabel(app.UIFigure);
            app.ftLabel.Text = 'f(t)=';
            app.ft = uieditfield(app.UIFigure, 'text');
            app.ft.Value = 't.*exp(-t)';
            app.ModificarLabel = uilabel(app.UIFigure);
            app.ModificarLabel.Text = 'Modificar';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs=';
            app.Fs = uieditfield(app.UIFigure, 'text');
            app.Fs.Value = '2';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n=';
            app.N = uieditfield(app.UIFigure, 'text');
            app.N.Value = '16';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EFM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end