classdef FFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TRANSFORMADARPIDADEFOURIERILabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        cal                        matlab.ui.control.Button
        grafica                    matlab.ui.control.Button
        limpiar                    matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Grafic                     matlab.ui.control.UIAxes
        xtLabel                    matlab.ui.control.Label
        xt                         matlab.ui.control.EditField
        OpcionesdegraficacinLabel  matlab.ui.control.Label
        Og                         matlab.ui.control.DropDown
        Label_3                    matlab.ui.control.Label
        Grafic_2                   matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            FG %Ejecuta dicho el archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            EFM
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calculo de la Transformada Rapida de Foureir
            syms t T
            %serie temporal
            x=str2sym(app.xt.Value);
            X(t)=x;
            x1=subs(X,t,T);
            y(T)=x1;
            n=2^14;
            dt=0.4;
            t=(0:n-1)*dt; %vector de tiempos
            T=-5:0.01:5;
            d=double(X(t));
            %amplitud-fase vs. frecuencias
            g=fft(d);
            power=abs(g).^2;
            dw=2*pi/(n*dt);
            w=(0:n-1)*dw; %vector de frecuencias angulares
            plot(app.Grafic,w,power, 'b')
            plot(app.Grafic_2,T,y(T), 'r')
            app.Grafic.XLim=[-inf inf]
        end

        % Button pushed function: grafica
        function graficaButtonPushed(app, event)
            %Grafica la Transformada Rapida de Foureir
            syms t T
            %serie temporal
            x=str2sym(app.xt.Value);
            X(t)=x;
            n=2^14;
            dt=0.4;
            t=(0:n-1)*dt; %vector de tiempos
            d=double(X(t));
            if (strcmp(app.Og.Value,'Negativo a Positivo'))
                %amplitud-fase vs. frecuencias
                y=fft(d);
                g=fftshift(y);
                power=abs(g).^2;
                dw=2*pi/(n*dt);
                w=(-n/2:n/2-1)*dw;
                plot(app.Grafic,w,power, 'b')
                app.Grafic.XLim=[-inf inf]
            elseif (strcmp(app.Og.Value,'Frecuencia de Nyquist'))
                %amplitud-fase vs. frecuencias
                g=fft(d);
                power=abs(g).^2;
                dw=2*pi/(n*dt);
                w=(0:n-1)*dw;
                wc=pi/dt; %frecuencia angular crítica
                plot(app.Grafic,w,power,'b')
                app.Grafic.XLim=[0 wc]
            end
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia cada casilla y restablece cada grafica
            app.xt.Value='';
            plot(app.Grafic,0,0);
            plot(app.Grafic_2,0,0);
            app.Grafic.XLim=[-inf inf]
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TRANSFORMADARPIDADEFOURIERILabel = uilabel(app.UIFigure);
            app.TRANSFORMADARPIDADEFOURIERILabel.Text = 'TRANSFORMADA RÁPIDA DE FOURIER (I)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'La transformada rápida de Fourier FFT es un algoritmo que reduce el tiempo de cálculo de n^2 pasos a '; 'n·log2(n).'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ingrese la señal x(t), Ejm. ';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.grafica = uibutton(app.UIFigure, 'push');
            app.grafica.ButtonPushedFcn = createCallbackFcn(app, @graficaButtonPushed, true);
            app.grafica.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Espectro de potencia')
            xlabel(app.Grafic, '\omega')
            ylabel(app.Grafic, 'p(\omega)')
            app.xtLabel = uilabel(app.UIFigure);
            app.xtLabel.Text = 'x(t)=';
            app.xt = uieditfield(app.UIFigure, 'text');
            app.xt.Value = 'cos(t)+0.5*cos(3*t)+0.4*cos(3.5*t)+0.7*cos(4*t)+0.2*cos(6*t)';
            app.OpcionesdegraficacinLabel = uilabel(app.UIFigure);
            app.OpcionesdegraficacinLabel.Text = {'Opciones de '; 'graficación'};
            app.Og = uidropdown(app.UIFigure);
            app.Og.Items = {'Negativo a Positivo', 'Frecuencia de Nyquist'};
            app.Og.Value = 'Negativo a Positivo';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '|';
            app.Grafic_2 = uiaxes(app.UIFigure);
            title(app.Grafic_2, 'Señal')
            xlabel(app.Grafic_2, 't')
            ylabel(app.Grafic_2, 'x(t)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = FFT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end