classdef FG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        FUNCINDEGAUSSLabel  matlab.ui.control.Label
        Label               matlab.ui.control.Label
        cal                 matlab.ui.control.Button
        limpiar             matlab.ui.control.Button
        Grafic              matlab.ui.control.UIAxes
        Label_2             matlab.ui.control.Label
        Grafic_2            matlab.ui.control.UIAxes
        ModificarLabel      matlab.ui.control.Label
        ftLabel             matlab.ui.control.Label
        Ft                  matlab.ui.control.EditField
        toLabel             matlab.ui.control.Label
        To                  matlab.ui.control.EditField
        woLabel             matlab.ui.control.Label
        Wo                  matlab.ui.control.EditField
        s2Label             matlab.ui.control.Label
        s                   matlab.ui.control.EditField
        fsLabel             matlab.ui.control.Label
        Fs                  matlab.ui.control.EditField
        nLabel_4            matlab.ui.control.Label
        N                   matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            EFM %Ejecuta dicho archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            FFT
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Realiza el calculo de la Función de Gauss
            syms t w0 t0 s2
            t0=str2double(app.To.Value);
            w0=str2double(app.Wo.Value);
            s2=str2double(app.s.Value);            
            t=0:0.05:16;
            F=str2sym(app.Ft.Value);
            f=subs(F);
            plot(app.Grafic_2,t,f,'b')
            fs=str2double(app.Fs.Value);
            n=str2double(app.N.Value);
            dt=1/fs;
            t=(0:n-1)*dt
            ft=subs(F)
            plot(app.Grafic_2,t,ft,'ro','markersize',2,'markeredgecolor','r','markerfacecolor','r')
            
            wc=pi*fs; %frecuencia límite de Nyquist
            w=-wc:0.1:wc;
            Fw=sqrt(2*pi*s2)*exp(-1i*w*t0).*(exp(1i*w0*t0)*exp(-(w-w0).^2*s2/2)+exp(-1i*w0*t0)*exp(-(w+w0).^2*s2/2))/2;
            
            %transformada rápida de Fourier
            ft=double(ft);
            y=fft(ft,n);
            g=fftshift(y);
            dw=2*pi/(n*dt);
            ww=(-n/2:n/2-1)*dw;
            plot(app.Grafic,w,abs(Fw),'b',ww,abs(g)/fs,'ro','markersize',2,'markeredgecolor','r','markerfacecolor','r')
            
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia los datos de cada casilla y restablece graficas
            app.Ft.Value='';
            app.To.Value='';
            app.Wo.Value='';
            app.s.Value='';
            app.Fs.Value='';
            app.N.Value='';
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
            app.Grafic_2.NextPlot='replacechildren';
            plot(app.Grafic_2,0,0);
            app.Grafic_2.NextPlot='add';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.FUNCINDEGAUSSLabel = uilabel(app.UIFigure);
            app.FUNCINDEGAUSSLabel.Text = 'FUNCIÓN DE GAUSS';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ingrese la función f(t), Ejm. ';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Transformada')
            xlabel(app.Grafic, '\omega')
            ylabel(app.Grafic, 'F(\omega)')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '|';
            app.Grafic_2 = uiaxes(app.UIFigure);
            title(app.Grafic_2, 'Función')
            xlabel(app.Grafic_2, 't')
            ylabel(app.Grafic_2, 'f(t)')
            app.ModificarLabel = uilabel(app.UIFigure);
            app.ModificarLabel.Text = 'Modificar';
            app.ftLabel = uilabel(app.UIFigure);
            app.ftLabel.Text = 'f(t)=';
            app.Ft = uieditfield(app.UIFigure, 'text');
            app.Ft.Value = 'exp(-(t-t0).^2/(2*s2)).*cos(w0*t)';
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to=';
            app.To = uieditfield(app.UIFigure, 'text');
            app.To.Value = '8';
            app.woLabel = uilabel(app.UIFigure);
            app.woLabel.Text = 'wo=';
            app.Wo = uieditfield(app.UIFigure, 'text');
            app.Wo.Value = '10';
            app.s2Label = uilabel(app.UIFigure);
            app.s2Label.Text = 's^2=';
            app.s = uieditfield(app.UIFigure, 'text');
            app.s.Value = '5';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs=';
            app.Fs = uieditfield(app.UIFigure, 'text');
            app.Fs.Value = '8';
            app.nLabel_4 = uilabel(app.UIFigure);
            app.nLabel_4.Text = 'n=';
            app.N = uieditfield(app.UIFigure, 'text');
            app.N.Value = '128';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FG

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end