classdef IF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        INTEGRALESDEFOURIERLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        cal                       matlab.ui.control.Button
        grafica                   matlab.ui.control.Button
        limpiar                   matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Grafic                    matlab.ui.control.UIAxes
        fxLabel                   matlab.ui.control.Label
        fx                        matlab.ui.control.EditField
        LLabel                    matlab.ui.control.Label
        l                         matlab.ui.control.EditField
        awLabel                   matlab.ui.control.Label
        a                         matlab.ui.control.EditField
        bwLabel                   matlab.ui.control.Label
        b                         matlab.ui.control.EditField
        FxLabel                   matlab.ui.control.Label
        FX                        matlab.ui.control.EditField
        FuncinLabel               matlab.ui.control.Label
        PoI                       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calculo de las Integrales de Foureir
            syms x n w 
            f = str2sym(app.fx.Value); %f(x)
            L=str2num(app.l.Value); %Periodo
            y(x)=f;
            %Analisa si la función es par o impar, y procede a calcular los coeficientes
            if y(-x) == y(x)
                app.PoI.Value='par';
                aw=1/pi*int(f*cos(w*x),-L,L);
                bw=0;
                app.a.Value=char(aw);
                app.b.Value=num2str(bw);
            elseif y(-x) == -y(x)
                app.PoI.Value='impar';
                aw=0;
                bw=2/pi*int(f*sin(w*x),-L,L);
                app.a.Value=num2str(aw);
                app.b.Value=char(bw);
            else
                app.PoI.Value='No es par ni impar';
                aw=1/pi*int(f*cos(w*x),-L,L);
                bw=1/pi*int(f*sin(w*x),-L,L);
                app.a.Value=char(aw);
                app.b.Value=char(bw);
                
            end
            app.FX.Value=char(int(aw*cos(w*x)+bw*sin(w*x),w,0,inf));
            %GRAFICA
            x=-L:0.1:L;
            plot(app.Grafic,x,y(x), 'r');
            
        end

        % Button pushed function: grafica
        function graficaButtonPushed(app, event)
            %Grafica de la integral de Fourier
            syms x n w
            f = str2sym(app.fx.Value);
            L=str2num(app.l.Value);
            y(x)=f;
            if y(-x) == y(x)
                app.PoI.Value='par';
                aw=1/pi*int(f*cos(w*x),-L,L);
                bw=0;
                app.a.Value=char(aw);
                app.b.Value=num2str(bw);
            elseif y(-x) == -y(x)
                app.PoI.Value='impar';
                aw=0;
                bw=2/pi*int(f*sin(w*x),-L,L);
                app.a.Value=num2str(aw);
                app.b.Value=char(bw);
            else
                app.PoI.Value='No es par ni impar';
                aw=1/pi*int(f*cos(w*x),-L,L);
                bw=1/pi*int(f*sin(w*x),-L,L);
                app.a.Value=char(aw);
                app.b.Value=char(bw);
                
            end
            app.FX.Value=char(int(aw*cos(w*x)+bw*sin(w*x),w,0,inf));
            %GRAFICA
            x=-L:0.1:L;
         
            If=int(aw*cos(w*x)+bw*sin(w*x),w,0,inf); %Integral de Fourier
            plot(app.Grafic,x,If, '-ob');
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia los datos de cada casilla y restablece la grafica
            app.fx.Value=''; 
            app.l.Value='';
            app.a.Value='';
            app.b.Value='';
            app.FX.Value='';
            app.PoI.Value='';
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
        end

        % Callback function
        function ButtonPushed(app, event)
            IFT %Ejecuta dicho archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            IFT
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.INTEGRALESDEFOURIERLabel = uilabel(app.UIFigure);
            app.INTEGRALESDEFOURIERLabel.Text = 'INTEGRALES DE FOURIER';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Es una forma de representar funciones no periódicas que estén definidas en un intervalo infinito o '; 'semi-infinito.'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ingrese f(x), Ejm. ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-L <= x <= L';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.grafica = uibutton(app.UIFigure, 'push');
            app.grafica.ButtonPushedFcn = createCallbackFcn(app, @graficaButtonPushed, true);
            app.grafica.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Integral de Fourier')
            xlabel(app.Grafic, 'x')
            ylabel(app.Grafic, 'f(x)')
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x)=';
            app.fx = uieditfield(app.UIFigure, 'text');
            app.fx.Value = 'exp(-abs(x))';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L=';
            app.l = uieditfield(app.UIFigure, 'text');
            app.l.Value = 'pi';
            app.awLabel = uilabel(app.UIFigure);
            app.awLabel.Text = 'aw';
            app.a = uieditfield(app.UIFigure, 'text');
            app.bwLabel = uilabel(app.UIFigure);
            app.bwLabel.Text = 'bw';
            app.b = uieditfield(app.UIFigure, 'text');
            app.FxLabel = uilabel(app.UIFigure);
            app.FxLabel.Text = 'F(x)';
            app.FX = uieditfield(app.UIFigure, 'text');
            app.FuncinLabel = uilabel(app.UIFigure);
            app.FuncinLabel.Text = 'Función:';
            app.PoI = uieditfield(app.UIFigure, 'text');
            app.PoI.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end