classdef IFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        INTEGRALESDEFOURIERATROZOSLabel  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        f               matlab.ui.control.EditField
        f1              matlab.ui.control.EditField
        cal             matlab.ui.control.Button
        graficar        matlab.ui.control.Button
        limpiar         matlab.ui.control.Button
        Button          matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        ParaLx0Label    matlab.ui.control.Label
        Para0xLLabel    matlab.ui.control.Label
        Grafic          matlab.ui.control.UIAxes
        awLabel         matlab.ui.control.Label
        a               matlab.ui.control.EditField
        bwLabel         matlab.ui.control.Label
        b               matlab.ui.control.EditField
        FxLabel         matlab.ui.control.Label
        FX              matlab.ui.control.EditField
        LLabel          matlab.ui.control.Label
        l               matlab.ui.control.EditField
        Caso1           matlab.ui.control.CheckBox
        Parax0xLLabel   matlab.ui.control.Label
        Para0xLLabel_2  matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Caso2           matlab.ui.control.CheckBox
        PasoLabel       matlab.ui.control.Label
        paso            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calcula la integral de Fourier a trozos
            syms x n x1 x2 xi w
            F = str2sym(app.f.Value); %Primer tramo
            F1 = str2sym(app.f1.Value); %Segundo tramo
            y(x)=F;
            y1(x)=F1;
            L=str2num(app.l.Value); %Periodo
            L1=double(0);
            L2=double(inf);
            L3=double(-inf);
            value=app.Caso1.Value;
            value1=app.Caso2.Value;
            %Dependiendo el caso seleccionado ejecuta dicho proceso para obtener los coeficientes y grafica
            if (value == 1) & (value1 == 0)
                %coeficientes
                aw=int(F*cos(w*x),-L,0)+int(F1*cos(w*x),0,L);
                bw=int(F*sin(w*x),-L,0)+int(F1*sin(w*x),0,L);
                app.a.Value=char(aw);
                app.b.Value=char(bw);
                app.FX.Value=char(1/pi*int(aw*cos(w*x)+bw*sin(w*x),0,inf));
                
                %GRAFICA <>
                x=-L:0.01:L;
                x1=-L:0.01:0;
                x2=0:0.01:L;
                plot(app.Grafic,x1, y(x1), '.r', x2, y1(x2), '.r');
            elseif (value == 0) & (value1 == 1)
                aw=int(F*cos(w*x),L3,L1)+int(F1*cos(w*x),L1,L)+int(F*cos(w*x),L,L2);
                bw=int(F*sin(w*x),L3,L1)+int(F1*sin(w*x),L1,L)+int(F*sin(w*x),L,L2);
                app.a.Value=char(aw);
                app.b.Value=char(bw);
                app.FX.Value=char(1/pi*int(aw*cos(w*x)+bw*sin(w*x),w,0,inf));
                
                x=-L:0.01:2*L;
                xi=-L:0.01:L1;
                x1=L1:0.01:L;
                x2=L:0.01:2*L;
                plot(app.Grafic,xi, y(xi), '.r', x1, y1(x1), '.r', x2, y(x2), '.r');
            end
            
        end

        % Button pushed function: graficar
        function graficarButtonPushed(app, event)
            %Grafica la integral de Fourier a Trozos dependiendo el caso seleccionado
            syms x n x1 x2 xi w
            F = str2sym(app.f.Value);
            F1 = str2sym(app.f1.Value);
            y(x)=F;
            y1(x)=F1;
            L=str2num(app.l.Value);
            L1=double(0);
            L2=double(inf);
            L3=double(-inf);
            value=app.Caso1.Value;
            value1=app.Caso2.Value;
            p=str2num(app.paso.Value);
            
            if (value == 1) & (value1 == 0)
                aw=int(F*cos(w*x),-L,0)+int(F1*cos(w*x),0,L);
                bw=int(F*sin(w*x),-L,0)+int(F1*sin(w*x),0,L);
                
                
                %GRAFICA <>
                x=-L:p:L;
                x1=-L:p:0;
                x2=0:p:L;
                If=1/pi*int(aw*cos(w*x)+bw*sin(w*x),w,0,inf);
                plot(app.Grafic,x, If, '-ob');
                
            elseif (value == 0) & (value1 == 1)
                aw=int(F*cos(w*x),L3,L1)+int(F1*cos(w*x),L1,L)+int(F*cos(w*x),L,L2);
                bw=int(F*sin(w*x),L3,L1)+int(F1*sin(w*x),L1,L)+int(F*sin(w*x),L,L2);
                
                %GRAFICA <>
                x=-L:p:2*L;
                xi=-L:p:L1;
                x1=L1:p:L;
                x2=L:p:2*L;
                If=1/pi*int(aw*cos(w*x)+bw*sin(w*x),w,0,inf);
                plot(app.Grafic,x,If, '-o b')
            end
            
            
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia cada dato de las casillas y restablece las graficas
            app.f.Value='';
            app.f1.Value='';
            app.l.Value='';
            app.a.Value='';
            app.b.Value='';
            app.FX.Value='';
            app.paso.Value='0.01';
            app.Caso1.Value=0;
            app.Caso2.Value=0;
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            IF %Ejecuta dicho archivo
            delete(app) %Cierra el programa actual
        end

        % Callback function
        function Button_4Pushed(app, event)
            IF
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.INTEGRALESDEFOURIERATROZOSLabel = uilabel(app.UIFigure);
            app.INTEGRALESDEFOURIERATROZOSLabel.Text = 'INTEGRALES DE FOURIER A TROZOS';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ingrese la función a trozos. Ejm.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'f(x)=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '{';
            app.f = uieditfield(app.UIFigure, 'text');
            app.f.Value = '3';
            app.f1 = uieditfield(app.UIFigure, 'text');
            app.f1.Value = '5';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.graficar = uibutton(app.UIFigure, 'push');
            app.graficar.ButtonPushedFcn = createCallbackFcn(app, @graficarButtonPushed, true);
            app.graficar.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.ParaLx0Label = uilabel(app.UIFigure);
            app.ParaLx0Label.Text = 'Para         -L <= x < 0';
            app.Para0xLLabel = uilabel(app.UIFigure);
            app.Para0xLLabel.Text = 'Para           0 <= x <= L';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Integral de Fourier')
            xlabel(app.Grafic, 'x')
            ylabel(app.Grafic, 'f(x)')
            app.awLabel = uilabel(app.UIFigure);
            app.awLabel.Text = 'aw';
            app.a = uieditfield(app.UIFigure, 'text');
            app.bwLabel = uilabel(app.UIFigure);
            app.bwLabel.Text = 'bw';
            app.b = uieditfield(app.UIFigure, 'text');
            app.FxLabel = uilabel(app.UIFigure);
            app.FxLabel.Text = 'F(x)';
            app.FX = uieditfield(app.UIFigure, 'text');
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L=';
            app.l = uieditfield(app.UIFigure, 'text');
            app.l.Value = '1';
            app.Caso1 = uicheckbox(app.UIFigure);
            app.Caso1.Text = 'Caso 1';
            app.Parax0xLLabel = uilabel(app.UIFigure);
            app.Parax0xLLabel.Text = 'Para        x < 0 & x > L';
            app.Para0xLLabel_2 = uilabel(app.UIFigure);
            app.Para0xLLabel_2.Text = 'Para                0 < x < L';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '---------------------------';
            app.Caso2 = uicheckbox(app.UIFigure);
            app.Caso2.Text = 'Caso 2';
            app.PasoLabel = uilabel(app.UIFigure);
            app.PasoLabel.Text = 'Paso=';
            app.paso = uieditfield(app.UIFigure, 'text');
            app.paso.Value = '0.05';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IFT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end