classdef SF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SERIESDEFOURIERLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        fxLabel               matlab.ui.control.Label
        fx                    matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        LLabel                matlab.ui.control.Label
        l                     matlab.ui.control.EditField
        Label_5               matlab.ui.control.Label
        cal                   matlab.ui.control.Button
        grafica               matlab.ui.control.Button
        limpiar               matlab.ui.control.Button
        a0Label               matlab.ui.control.Label
        ao                    matlab.ui.control.EditField
        anLabel               matlab.ui.control.Label
        an                    matlab.ui.control.EditField
        bnLabel               matlab.ui.control.Label
        bn                    matlab.ui.control.EditField
        FxLabel               matlab.ui.control.Label
        FX                    matlab.ui.control.EditField
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Grafic                matlab.ui.control.UIAxes
        nLabel                matlab.ui.control.Label
        N                     matlab.ui.control.EditField
        FuncinLabel           matlab.ui.control.Label
        PoI                   matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            % Envia valores de '' a cada casilla, esto sirve para limpiar o eliminar los datos de las casillas
            % Es decir los deja por default
            app.fx.Value='';
            app.l.Value='';
            app.ao.Value='';
            app.an.Value='';
            app.bn.Value='';
            app.FX.Value='';
            app.N.Value='';
            app.PoI.Value='';
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            SFC %Ejecuta el archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            SF1
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Realiza el calculo de la Serie de Fourier
            syms x n %Declara variables simbolicas
            f = str2sym(app.fx.Value); %Transforma el texto ingresado a una función simbolica
            L=str2num(app.l.Value); %Transforma el texto ingresado a un numero double
            y(x)=f
            % Analiza si la función es par o impar para obtener los coeficientes de la mejor manera.
            if y(-x) == y(x)
                app.PoI.Value='par'; %Envia datos a la casilla correspondiente
                a0=1/L*int(f,-L,L);
                an1=1/L*int(f*cos((n*pi*x)/L),-L,L);
                bn1=0;
                app.ao.Value=char(a0);
                app.an.Value=char(an1);
                app.bn.Value=num2str(bn1);
            elseif y(-x) == -y(x)
                app.PoI.Value='impar';
                a0=0;
                an1=0;
                bn1=1/L*int(f*sin((n*pi*x)/L),-L,L);
                app.ao.Value=num2str(a0);
                app.an.Value=num2str(an1);
                app.bn.Value=char(bn1);
            else
                app.PoI.Value='No es par ni impar';
                a0=1/L*int(f,-L,L);
                an1=1/L*int(f*cos((n*pi*x)/L),-L,L);
                bn1=1/L*int(f*sin((n*pi*x)/L),-L,L);
                app.ao.Value=char(a0);
                app.an.Value=char(an1);
                app.bn.Value=char(bn1);
            end
            app.FX.Value=char(a0/2+an1*cos((n*pi*x)/L)+bn1*sin((n*pi*x)/L)); %Serie de Fourier sin resolver, F(x)
            
            x=-L:0.01:L; %Intervalo
            plot(app.Grafic,x,y(x), 'b') %Grafica la función f(x)
            
        end

        % Button pushed function: grafica
        function graficaButtonPushed(app, event)
            %Grafica de Series de Fourier
            syms x n
            f = str2sym(app.fx.Value)
            y(x)=f
            L=str2num(app.l.Value);
            x=-L:0.01:L;
            
            a0=str2sym(app.ao.Value)
            an1=str2sym(app.an.Value)
            bn1=str2sym(app.bn.Value)
            if (strcmp(app.N.Value,'0')) %Analisa el valor de 'N' y dependiendo del dato elimina las graficas o grafica
                app.Grafic.NextPlot='replacechildren';
                plot(app.Grafic,x,y(x), 'b'); %Elimina las graficas de la serie de Fourier
                app.Grafic.NextPlot='add';
            else
                k=str2num(app.N.Value) %Dato de n
                SF=zeros(1, length(x));
                for i=1:k
                    A0=subs(a0,n,i);
                    An=subs(an1,n,i);
                    Bn=subs(bn1,n,i);
                    SF=SF+An*cos((i*pi*x)/L)+Bn*sin((i*pi*x)/L); 
                    X=A0/2+SF; %Serie de Fourier
                end
                plot(app.Grafic,x,X, 'r'); %Grafica la serie de Fourier
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SERIESDEFOURIERLabel = uilabel(app.UIFigure);
            app.SERIESDEFOURIERLabel.Text = 'SERIES DE FOURIER';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Es una serie infinita que converge puntualmente a una función periódica y continua a trozos (o por partes).';
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x)=';
            app.fx = uieditfield(app.UIFigure, 'text');
            app.fx.Value = 'x';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ingrese f(x), Ejm. ';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L=';
            app.l = uieditfield(app.UIFigure, 'text');
            app.l.Value = 'pi';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-L <= x <= L';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.grafica = uibutton(app.UIFigure, 'push');
            app.grafica.ButtonPushedFcn = createCallbackFcn(app, @graficaButtonPushed, true);
            app.grafica.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.a0Label = uilabel(app.UIFigure);
            app.a0Label.Text = 'a0';
            app.ao = uieditfield(app.UIFigure, 'text');
            app.anLabel = uilabel(app.UIFigure);
            app.anLabel.Text = 'an';
            app.an = uieditfield(app.UIFigure, 'text');
            app.bnLabel = uilabel(app.UIFigure);
            app.bnLabel.Text = 'bn';
            app.bn = uieditfield(app.UIFigure, 'text');
            app.FxLabel = uilabel(app.UIFigure);
            app.FxLabel.Text = 'F(x)';
            app.FX = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Serie de Fourier')
            xlabel(app.Grafic, 'x')
            ylabel(app.Grafic, 'f(x)')
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n=';
            app.N = uieditfield(app.UIFigure, 'text');
            app.N.Value = '7';
            app.FuncinLabel = uilabel(app.UIFigure);
            app.FuncinLabel.Text = 'Función';
            app.PoI = uieditfield(app.UIFigure, 'text');
            app.PoI.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end