classdef SF1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SERIESDEFOURIERATROZOSLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        f                            matlab.ui.control.EditField
        f1                           matlab.ui.control.EditField
        cal                          matlab.ui.control.Button
        graficar                     matlab.ui.control.Button
        limpiar                      matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        ParaLx0Label                 matlab.ui.control.Label
        Para0xLLabel                 matlab.ui.control.Label
        Grafic                       matlab.ui.control.UIAxes
        a0Label                      matlab.ui.control.Label
        ao                           matlab.ui.control.EditField
        anLabel                      matlab.ui.control.Label
        an                           matlab.ui.control.EditField
        bnLabel                      matlab.ui.control.Label
        bn                           matlab.ui.control.EditField
        FxLabel                      matlab.ui.control.Label
        FX                           matlab.ui.control.EditField
        nLabel                       matlab.ui.control.Label
        N                            matlab.ui.control.EditField
        Button_4                     matlab.ui.control.Button
        LLabel                       matlab.ui.control.Label
        l                            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            SF %Ejecuta dicho archivo
            delete(app) % Cierra el programa actual
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            SFC
            delete(app)
        end

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calcula la Serie de Fourier a trozos
            syms x n % Declara variables simbolicas
            F = str2sym(app.f.Value); % Convierte el texto ingresado en una función simbolica en este caso el primer tramo
            F1 = str2sym(app.f1.Value); %Segundo tramo de la función
            L=str2num(app.l.Value); %Periodo
            y(x)=F;
            y1(x)=F1;
            %Coeficientes
            a0=1/L*(int(F,-L,0)+int(F1,0,L));
            an1=1/L*(int(F*cos((n*pi*x)/L),-L,0)+int(F1*cos((n*pi*x)/L),0,L));
            bn1=1/L*(int(F*sin((n*pi*x)/L),-L,0)+int(F1*sin((n*pi*x)/L),0,L));
            %Envia el dato a las casillas
            app.ao.Value=char(a0);
            app.an.Value=char(an1);
            app.bn.Value=char(bn1);
            app.FX.Value=char(a0/2+an1*cos((n*pi*x)/L)+bn1*sin((n*pi*x)/L)); %Serie de Fourier sin resolver
            
            %GRAFICA <>
            x=-L:0.01:L; %Intervalos
            x1=-L:0.01:0;
            x2=0:0.01:L;
            plot(app.Grafic,x1, y(x1), 'r', x2, y1(x2), 'r'); %Grafica f(x)
            
        end

        % Button pushed function: graficar
        function graficarButtonPushed(app, event)
            %Grafica la Serie de Fourier
            syms x n
            F = str2sym(app.f.Value);
            F1 = str2sym(app.f1.Value);
            y(x)=F;
            y1(x)=F1;
            L=str2num(app.l.Value);
            x=-L:0.01:L;
            x1=-L:0.01:0;
            x2=0:0.01:L;
            
            a0=str2sym(app.ao.Value);
            an1=str2sym(app.an.Value);
            bn1=str2sym(app.bn.Value);
            if (strcmp(app.N.Value,'0')) %Analisa el valor de 'N' y dependiendo del dato elimina las graficas o grafica
                app.Grafic.NextPlot='replacechildren';
                plot(app.Grafic,x1, y(x1), 'r', x2, y1(x2), 'r'); %Elimina la grafica de F(x)
                app.Grafic.NextPlot='add';
            else
                k=str2num(app.N.Value); %Dato de n
                SF=zeros(1, length(x));
                for i=1:k
                    A0=subs(a0,n,i);
                    An=subs(an1,n,i);
                    Bn=subs(bn1,n,i);
                    SF=SF+An*cos((i*pi*x)/L)+Bn*sin((i*pi*x)/L);
                    X=A0/2+SF; %Serie de Fourier
                end
                plot(app.Grafic,x,X, 'b'); %Grafica la Serie de Fourier
            end
            
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia los datos de cada casilla y de cada grafica.
            app.f.Value='';
            app.l.Value='';
            app.ao.Value='';
            app.an.Value='';
            app.bn.Value='';
            app.FX.Value='';
            app.N.Value='';
            app.f1.Value='';
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SERIESDEFOURIERATROZOSLabel = uilabel(app.UIFigure);
            app.SERIESDEFOURIERATROZOSLabel.Text = 'SERIES DE FOURIER A TROZOS';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ingrese la función a trozos. Ejm.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'f(x)=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '{';
            app.f = uieditfield(app.UIFigure, 'text');
            app.f.Value = '-x';
            app.f1 = uieditfield(app.UIFigure, 'text');
            app.f1.Value = '1/2';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.graficar = uibutton(app.UIFigure, 'push');
            app.graficar.ButtonPushedFcn = createCallbackFcn(app, @graficarButtonPushed, true);
            app.graficar.Text = 'Graficar';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.ParaLx0Label = uilabel(app.UIFigure);
            app.ParaLx0Label.Text = 'Para         -L <= x < 0';
            app.Para0xLLabel = uilabel(app.UIFigure);
            app.Para0xLLabel.Text = 'Para           0 <= x <= L';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Serie de Fourier')
            xlabel(app.Grafic, 'x')
            ylabel(app.Grafic, 'f(x)')
            app.a0Label = uilabel(app.UIFigure);
            app.a0Label.Text = 'a0';
            app.ao = uieditfield(app.UIFigure, 'text');
            app.anLabel = uilabel(app.UIFigure);
            app.anLabel.Text = 'an';
            app.an = uieditfield(app.UIFigure, 'text');
            app.bnLabel = uilabel(app.UIFigure);
            app.bnLabel.Text = 'bn';
            app.bn = uieditfield(app.UIFigure, 'text');
            app.FxLabel = uilabel(app.UIFigure);
            app.FxLabel.Text = 'F(x)';
            app.FX = uieditfield(app.UIFigure, 'text');
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n=';
            app.N = uieditfield(app.UIFigure, 'text');
            app.N.Value = '7';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L=';
            app.l = uieditfield(app.UIFigure, 'text');
            app.l.Value = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SF1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end