classdef SFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SERIESCOMPLEJASDEFOURIERLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        cal                            matlab.ui.control.Button
        limpiar                        matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Grafic                         matlab.ui.control.UIAxes
        Button_4                       matlab.ui.control.Button
        CoLabel                        matlab.ui.control.Label
        ao                             matlab.ui.control.EditField
        CkLabel                        matlab.ui.control.Label
        an                             matlab.ui.control.EditField
        nLabel                         matlab.ui.control.Label
        N                              matlab.ui.control.EditField
        ALabel                         matlab.ui.control.Label
        A                              matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        k0CoApiaLabel                  matlab.ui.control.Label
        k0CkAkpisinkaLabel             matlab.ui.control.Label
        aLabel                         matlab.ui.control.Label
        a                              matlab.ui.control.EditField
        Label_8                        matlab.ui.control.Label
        cal_2                          matlab.ui.control.Button
        limpiar_2                      matlab.ui.control.Button
        Grafic_2                       matlab.ui.control.UIAxes
        CkLabel_2                      matlab.ui.control.Label
        an_2                           matlab.ui.control.EditField
        nLabel_2                       matlab.ui.control.Label
        N_2                            matlab.ui.control.EditField
        ALabel_2                       matlab.ui.control.Label
        A_2                            matlab.ui.control.EditField
        pLabel_2                       matlab.ui.control.Label
        p                              matlab.ui.control.EditField
        pLabel                         matlab.ui.control.Label
        P                              matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: cal
        function calButtonPushed(app, event)
            %Calculo de cada ejemplo
            a1=str2num(app.a.Value) %Semianchura del pulso rectangular a<pi
            n=str2num(app.N.Value) %número de terminos
            A1=str2num(app.A.Value)
            P1=str2num(app.p.Value)
            x=[-P1 -a1 -a1 a1 a1 P1];
            y=[0 0 A1 A1 0 0];
            plot(app.Grafic,x,y,'b','linewidth',1.5) %Grafica la fución
            x=linspace(-P1,P1,100);
            y=zeros(length(x),1);
            %Serie compleja de Fourier
            for j=1:length(x)
                y(j)=0;
                for k=-n:n
                    if k==0 
                        y(j)=y(j)+a1/pi;
                    else
                        y(j)=y(j)+sin(k*a1)*exp(1i*k*x(j))/(k*pi);
                    end
                end
            end
            %ejes
            plot(app.Grafic,[-4 4],[0 0],'k')
            plot(app.Grafic,[0 0],[-0.2 1.2],'k')
            %serie de Fourier
            plot(app.Grafic,x,real(y),'r');
            
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            %Limpia cada casilla
            app.A.Value='';
            app.a.Value='';
            app.N.Value='';
            app.p.Value='';
            app.Grafic.NextPlot='replacechildren';
            plot(app.Grafic,0,0);
            app.Grafic.NextPlot='add';
        end

        % Button pushed function: cal_2
        function cal_2ButtonPushed(app, event)
            %Calculo del segundo ejemplo
            n1=str2num(app.N_2.Value) %número de terminos
            A2=str2num(app.A_2.Value)
            P2=str2num(app.P.Value)
            plot(app.Grafic_2,[-P2,P2],[-A2 A2],'b','linewidth',1.5)
            x1=linspace(-P2,P2,100);
            y1=zeros(length(x1),1);
            for j=1:length(x1)
                y1(j)=0;
                for k=-n1:n1
                    y1(j)=y1(j)+(-1)^k*1i*exp(1i*k*x1(j))/(k*pi);
                end
            end
            %ejes
            plot(app.Grafic_2,[-4 4],[0 0],'k')
            plot(app.Grafic_2,[0 0],[-1.5 1.5],'k')
            %serie de Fourier
            plot(app.Grafic_2,x1,real(y1), 'r');
            
        end

        % Button pushed function: limpiar_2
        function limpiar_2ButtonPushed(app, event)
            %Limpia datos de las casillas del segundo ejemplo
            app.A_2.Value='';
            app.N_2.Value='';
            app.P.Value='';
            app.Grafic_2.NextPlot='replacechildren';
            plot(app.Grafic_2,0,0);
            app.Grafic_2.NextPlot='add';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            SF1 %Ejecuta dicho archivo
            delete(app) %Cierra el programa actual
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            SF
            delete(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            temas
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SERIESCOMPLEJASDEFOURIERLabel = uilabel(app.UIFigure);
            app.SERIESCOMPLEJASDEFOURIERLabel.Text = 'SERIES COMPLEJAS DE FOURIER';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PULSO RECTANGULAR:';
            app.cal = uibutton(app.UIFigure, 'push');
            app.cal.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.cal.Text = 'Calcular';
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Text = 'Limpiar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'exit.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'home.png';
            app.Button_3.Text = '';
            app.Grafic = uiaxes(app.UIFigure);
            title(app.Grafic, 'Aproximación de Fourier')
            xlabel(app.Grafic, 't')
            ylabel(app.Grafic, 'f(t)')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'next.png';
            app.Button_4.Text = '';
            app.CoLabel = uilabel(app.UIFigure);
            app.CoLabel.Enable = 'off';
            app.CoLabel.Text = 'Co';
            app.ao = uieditfield(app.UIFigure, 'text');
            app.ao.Enable = 'off';
            app.ao.Value = 'A/pi*a';
            app.CkLabel = uilabel(app.UIFigure);
            app.CkLabel.Enable = 'off';
            app.CkLabel.Text = 'Ck';
            app.an = uieditfield(app.UIFigure, 'text');
            app.an.Enable = 'off';
            app.an.Value = 'A/(k*pi)*sin(k*a)';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n=';
            app.N = uieditfield(app.UIFigure, 'text');
            app.N.Value = '7';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A=';
            app.A = uieditfield(app.UIFigure, 'text');
            app.A.Value = '1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Ejm.';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'PULSO DIENTE DE SIERRA:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Ck=';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '{';
            app.k0CoApiaLabel = uilabel(app.UIFigure);
            app.k0CoApiaLabel.Text = 'k=0           Co =  A/pi*a';
            app.k0CkAkpisinkaLabel = uilabel(app.UIFigure);
            app.k0CkAkpisinkaLabel.Text = 'k ~= 0        Ck = A/(k*pi)*sin(k*a)';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a=';
            app.a = uieditfield(app.UIFigure, 'text');
            app.a.Value = '1';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'|'; '|'; '|   '; '|'; '|'; '|'; '|'};
            app.cal_2 = uibutton(app.UIFigure, 'push');
            app.cal_2.ButtonPushedFcn = createCallbackFcn(app, @cal_2ButtonPushed, true);
            app.cal_2.Text = 'Calcular';
            app.limpiar_2 = uibutton(app.UIFigure, 'push');
            app.limpiar_2.ButtonPushedFcn = createCallbackFcn(app, @limpiar_2ButtonPushed, true);
            app.limpiar_2.Text = 'Limpiar';
            app.Grafic_2 = uiaxes(app.UIFigure);
            title(app.Grafic_2, 'Aproximación de Fourier')
            xlabel(app.Grafic_2, 't')
            ylabel(app.Grafic_2, 'f(t)')
            app.CkLabel_2 = uilabel(app.UIFigure);
            app.CkLabel_2.Enable = 'off';
            app.CkLabel_2.Text = 'Ck';
            app.an_2 = uieditfield(app.UIFigure, 'text');
            app.an_2.Enable = 'off';
            app.an_2.Value = 'A/(k*pi)*i*(-1)^k';
            app.nLabel_2 = uilabel(app.UIFigure);
            app.nLabel_2.Text = 'n=';
            app.N_2 = uieditfield(app.UIFigure, 'text');
            app.N_2.Value = '7';
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = 'A=';
            app.A_2 = uieditfield(app.UIFigure, 'text');
            app.A_2.Value = '1';
            app.pLabel_2 = uilabel(app.UIFigure);
            app.pLabel_2.Text = 'p=';
            app.p = uieditfield(app.UIFigure, 'text');
            app.p.Value = 'pi';
            app.pLabel = uilabel(app.UIFigure);
            app.pLabel.Text = 'p=';
            app.P = uieditfield(app.UIFigure, 'text');
            app.P.Value = 'pi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SFC

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end