classdef temas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TEMASLabel                    matlab.ui.control.Label
        SeriesdeFourierButton         matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        IntegralesdeFourierButton     matlab.ui.control.Button
        TransformadasdeFourierButton  matlab.ui.control.Button
        FuncionesLabel                matlab.ui.control.Label
        Sub                           matlab.ui.control.DropDown
        Go                            matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            caratula
            delete(app)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            delete(app)
        end

        % Button pushed function: SeriesdeFourierButton
        function SeriesdeFourierButtonPushed(app, event)
            app.Sub.Items={'Función' 'A trozos' 'Compleja'} %Envia esos datos a la barra deslizable
        
        end

        % Callback function
        function NumeroscomplejosButtonPushed(app, event)
            
        end

        % Callback function
        function FormaPolarButtonPushed(app, event)

        end

        % Callback function
        function FuncionesdevariablecomplejaButtonPushed(app, event)

        end

        % Callback function
        function FuncionespolinomicasyracionalesButtonPushed(app, event)

        end

        % Value changed function: Sub
        function SubValueChanged(app, event)

        end

        % Button pushed function: IntegralesdeFourierButton
        function IntegralesdeFourierButtonPushed(app, event)
            app.Sub.Items={' Función' ' A trozos'}
        end

        % Button pushed function: TransformadasdeFourierButton
        function TransformadasdeFourierButtonPushed(app, event)
            app.Sub.Items={'Transformada de Fourier Rapida (I)' 'Efecto de la frecuencia de muestreo fs' 'Función de Gauss'}
        end

        % Button pushed function: Go
        function GoButtonPushed(app, event)
           % Dependiendo de que seleccione en la barra deslizable al darle al boton 'Go!' nos enviara hacia un subtema.
            if (strcmp(app.Sub.Value,'Función'))
                SF
                delete(app)
            elseif (strcmp(app.Sub.Value,'A trozos'))
                SF1
                delete(app)
            elseif (strcmp(app.Sub.Value,'Compleja'))
                SFC
                delete(app)
            elseif (strcmp(app.Sub.Value,' Función'))
                IF
                delete(app)
            elseif (strcmp(app.Sub.Value,' A trozos'))
                IFT
                delete(app)
            elseif (strcmp(app.Sub.Value,'Transformada de Fourier Rapida (I)'))
                FFT
                delete(app)
            elseif (strcmp(app.Sub.Value,'Efecto de la frecuencia de muestreo fs'))
                EFM
                delete(app)
            elseif (strcmp(app.Sub.Value,'Función de Gauss'))
                FG
                delete(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TEMASLabel = uilabel(app.UIFigure);
            app.TEMASLabel.Text = 'TEMAS';
            app.SeriesdeFourierButton = uibutton(app.UIFigure, 'push');
            app.SeriesdeFourierButton.ButtonPushedFcn = createCallbackFcn(app, @SeriesdeFourierButtonPushed, true);
            app.SeriesdeFourierButton.Text = 'Series de Fourier';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'back.png';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'exit.png';
            app.Button_8.Text = '';
            app.IntegralesdeFourierButton = uibutton(app.UIFigure, 'push');
            app.IntegralesdeFourierButton.ButtonPushedFcn = createCallbackFcn(app, @IntegralesdeFourierButtonPushed, true);
            app.IntegralesdeFourierButton.Text = 'Integrales de Fourier';
            app.TransformadasdeFourierButton = uibutton(app.UIFigure, 'push');
            app.TransformadasdeFourierButton.ButtonPushedFcn = createCallbackFcn(app, @TransformadasdeFourierButtonPushed, true);
            app.TransformadasdeFourierButton.Text = 'Transformadas de Fourier';
            app.FuncionesLabel = uilabel(app.UIFigure);
            app.FuncionesLabel.Text = 'Funciones';
            app.Sub = uidropdown(app.UIFigure);
            app.Sub.Items = {};
            app.Sub.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub.Value = {};
            app.Go = uibutton(app.UIFigure, 'push');
            app.Go.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.Go.Text = 'Go!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = temas

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end